classdef gainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GainLabel                  matlab.ui.control.Label
        ParametersPanel            matlab.ui.container.Panel
        EnterGaindBEditFieldLabel  matlab.ui.control.Label
        GainValue                  matlab.ui.control.EditField
        OptionsPanel               matlab.ui.container.Panel
        CancelButton               matlab.ui.control.Button
        SaveExitButton             matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        gain;
        edit_flag
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
           app.CallingApp = mainapp;
           app.G = G;
           app.des = des;
           app.edit_flag = EditMode;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            gain_flag = 0;
            Gain = 1;
            noEdit = 0;
            updateGainCont(app.CallingApp,app.G,app.des,Gain,gain_flag,noEdit);
            delete(app);
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            app.gain = db2mag(str2double(app.GainValue.Value));
            gain_flag = 1;
            updateGainCont(app.CallingApp,app.G,app.des,app.gain,gain_flag,app.edit_flag);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GainLabel = uilabel(app.UIFigure);
            app.GainLabel.Text = 'Gain';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.EnterGaindBEditFieldLabel = uilabel(app.ParametersPanel);
            app.EnterGaindBEditFieldLabel.Text = 'Enter Gain [dB] :';
            app.GainValue = uieditfield(app.ParametersPanel, 'text');
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gainGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end