classdef lagGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Title                          matlab.ui.control.Label
        ParametersPanel                matlab.ui.container.Panel
        FrequencyradsecEditFieldLabel  matlab.ui.control.Label
        Freq                           matlab.ui.control.EditField
        BetaEditFieldLabel             matlab.ui.control.Label
        Beta                           matlab.ui.control.EditField
        EnterButton                    matlab.ui.control.Button
        ReadyLampLabel                 matlab.ui.control.Label
        Ready                          matlab.ui.control.Lamp
        OptionsPanel                   matlab.ui.container.Panel
        HelpButton                     matlab.ui.control.Button
        SaveExitButton                 matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        ControllerBodePanel            matlab.ui.container.Panel
        Bode_mag                       matlab.ui.control.UIAxes
        Bode_phase                     matlab.ui.control.UIAxes
        swnLabel                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        swnbetaLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        freq;
        beta;
        edit_flag; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
            app.CallingApp = mainapp;
            app.G = G;
            app.des = des;
            app.edit_flag = EditMode;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            clag = Clag(str2double(app.Freq.Value),str2double(app.Beta.Value));
            
            if ~isempty(str2double(app.Freq.Value)) && ~isempty(str2double(app.Beta.Value))
                app.Ready.Color = [0,1,0];
            end
            
            [mag, phase_local, w0] =bode(clag);
            semilogx(app.Bode_mag,w0,squeeze(mag(1,1,:)));
            semilogx(app.Bode_phase,w0,squeeze(phase_local(1,1,:)));
            app.freq = str2double(app.Freq.Value);
            app.beta = str2double(app.Beta.Value);
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            lag_flag = 1;
            updateLagCont(app.CallingApp,app.G,app.des,app.freq,app.beta,lag_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            lag_flag = 0;
            updateLagCont(app.CallingApp,app.G,app.des,app.freq,app.beta,lag_flag,app.edit_flag)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Lag Controller';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.FrequencyradsecEditFieldLabel = uilabel(app.ParametersPanel);
            app.FrequencyradsecEditFieldLabel.Text = 'Frequency [rad/sec]';
            app.Freq = uieditfield(app.ParametersPanel, 'text');
            app.BetaEditFieldLabel = uilabel(app.ParametersPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.Beta = uieditfield(app.ParametersPanel, 'text');
            app.EnterButton = uibutton(app.ParametersPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.Ready = uilamp(app.ParametersPanel);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.Text = 'Help';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ControllerBodePanel = uipanel(app.UIFigure);
            app.ControllerBodePanel.Title = 'Controller Bode';
            app.Bode_mag = uiaxes(app.ControllerBodePanel);
            title(app.Bode_mag, 'Mag')
            xlabel(app.Bode_mag, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag, 'Mag')
            app.Bode_phase = uiaxes(app.ControllerBodePanel);
            title(app.Bode_phase, 'Phase')
            xlabel(app.Bode_phase, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase, 'Phase')
            app.swnLabel = uilabel(app.UIFigure);
            app.swnLabel.Text = '10*s+wn';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '---------------';
            app.swnbetaLabel = uilabel(app.UIFigure);
            app.swnbetaLabel.Text = '10*s+wn/beta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lagGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end