classdef leadLagGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        FirstOrderTab           matlab.ui.container.Tab
        Title                   matlab.ui.control.Label
        ssqrtawnLabel           matlab.ui.control.Label
        sqrtaswnLabel           matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ParametersPanel         matlab.ui.container.Panel
        PhasedegLabel           matlab.ui.control.Label
        Phase                   matlab.ui.control.EditField
        DesiredFreqradsecLabel  matlab.ui.control.Label
        Freq                    matlab.ui.control.EditField
        AddButton               matlab.ui.control.Button
        ReadyLampLabel          matlab.ui.control.Label
        Ready                   matlab.ui.control.Lamp
        Inc_freq                matlab.ui.control.Button
        Dec_freq                matlab.ui.control.Button
        Inc_phase               matlab.ui.control.Button
        Dec_phase               matlab.ui.control.Button
        OptionsPanel            matlab.ui.container.Panel
        SaveExitButton          matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        HelpButton              matlab.ui.control.Button
        ControllerBodePanel     matlab.ui.container.Panel
        Bode_mag                matlab.ui.control.UIAxes
        Bode_phase              matlab.ui.control.UIAxes
        SecondOrderTab          matlab.ui.container.Tab
        Title_2                 matlab.ui.control.Label
        ParametersPanel_2       matlab.ui.container.Panel
        PhasedegLabel_2         matlab.ui.control.Label
        Phase_2                 matlab.ui.control.EditField
        FreqradsecLabel         matlab.ui.control.Label
        Freq_2                  matlab.ui.control.EditField
        AddButton_2             matlab.ui.control.Button
        ReadyLampLabel_2        matlab.ui.control.Label
        Ready_2                 matlab.ui.control.Lamp
        Inc_freq_2              matlab.ui.control.Button
        Dec_freq_2              matlab.ui.control.Button
        Inc_phase_2             matlab.ui.control.Button
        Dec_phase_2             matlab.ui.control.Button
        ZetaEditFieldLabel      matlab.ui.control.Label
        ZetaEditField           matlab.ui.control.EditField
        Inc_phase_3             matlab.ui.control.Button
        Dec_phase_3             matlab.ui.control.Button
        ControllerBodePanel_2   matlab.ui.container.Panel
        Bode_mag_2              matlab.ui.control.UIAxes
        Bode_phase_2            matlab.ui.control.UIAxes
        OptionsPanel_2          matlab.ui.container.Panel
        SaveExitButton_2        matlab.ui.control.Button
        CancelButton_2          matlab.ui.control.Button
        HelpButton_2            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        phase;
        freq;
        order;
        edit_flag;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
           app.CallingApp = mainapp;
           app.G = G;
           app.des = des;
           app.edit_flag = EditMode;
           app.Freq.Value = num2str(1);
           app.Phase.Value = num2str(30);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            clead = Clead(str2double(app.Phase.Value),str2double(app.Freq.Value),1);
            [mag, phase_local, w0] =bode(clead);
            semilogx(app.Bode_mag,w0,squeeze(mag(1,1,:)));
            semilogx(app.Bode_phase,w0,squeeze(phase_local(1,1,:)));
            app.phase = str2double(app.Phase.Value);
            app.freq = str2double(app.Freq.Value);
            app.order = 1;
            
            if ~isempty(app.phase) && ~isempty(app.freq)
                app.Ready.Color = [0,1,0];
            end
            
            lead_flag = 1;
            updateLeadCont(app.CallingApp,app.G,app.des,app.phase,app.freq,app.order,lead_flag,100)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            lead_flag = 0;
            updateLeadCont(app.CallingApp,app.G,app.des,app.phase,app.freq,app.order,lead_flag,app.edit_flag )
            delete(app);
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            lead_flag = 1;
            updateLeadCont(app.CallingApp,app.G,app.des,app.phase,app.freq,app.order,lead_flag,app.edit_flag )
            delete(app);
        end

        % Button pushed function: Inc_freq
        function Inc_freqButtonPushed(app, event)
            app.freq = str2double(app.Freq.Value);
            newFreq = app.freq + 1;
            app.Freq.Value = num2str(newFreq);
        end

        % Button pushed function: Dec_freq
        function Dec_freqButtonPushed(app, event)
            app.freq = str2double(app.Freq.Value);
            newFreq = app.freq*0.8;
            app.Freq.Value = num2str(newFreq);
        end

        % Button pushed function: Inc_phase
        function Inc_phaseButtonPushed(app, event)
            app.phase = str2double(app.Phase.Value);
            newPhase = app.phase + 5;
            app.Phase.Value = num2str(newPhase);
        end

        % Button pushed function: Dec_phase
        function Dec_phaseButtonPushed(app, event)
            app.phase = str2double(app.Phase.Value);
            newPhase = app.phase - 5;
            app.Phase.Value = num2str(newPhase);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpGUI('lead')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FirstOrderTab = uitab(app.TabGroup);
            app.FirstOrderTab.Title = 'First Order';
            app.Title = uilabel(app.FirstOrderTab);
            app.Title.Text = 'First Order Lead  Controller';
            app.ssqrtawnLabel = uilabel(app.FirstOrderTab);
            app.ssqrtawnLabel.Text = 's+sqrt(a)*wn';
            app.sqrtaswnLabel = uilabel(app.FirstOrderTab);
            app.sqrtaswnLabel.Text = 'sqrt(a)*s+wn';
            app.Label = uilabel(app.FirstOrderTab);
            app.Label.Text = '-----------------';
            app.ParametersPanel = uipanel(app.FirstOrderTab);
            app.ParametersPanel.Title = 'Parameters';
            app.PhasedegLabel = uilabel(app.ParametersPanel);
            app.PhasedegLabel.Text = 'Phase [deg]:';
            app.Phase = uieditfield(app.ParametersPanel, 'text');
            app.DesiredFreqradsecLabel = uilabel(app.ParametersPanel);
            app.DesiredFreqradsecLabel.Text = 'Desired Freq[rad/sec]:';
            app.Freq = uieditfield(app.ParametersPanel, 'text');
            app.AddButton = uibutton(app.ParametersPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.Ready = uilamp(app.ParametersPanel);
            app.Inc_freq = uibutton(app.ParametersPanel, 'push');
            app.Inc_freq.ButtonPushedFcn = createCallbackFcn(app, @Inc_freqButtonPushed, true);
            app.Inc_freq.Text = '+';
            app.Dec_freq = uibutton(app.ParametersPanel, 'push');
            app.Dec_freq.ButtonPushedFcn = createCallbackFcn(app, @Dec_freqButtonPushed, true);
            app.Dec_freq.Text = '-';
            app.Inc_phase = uibutton(app.ParametersPanel, 'push');
            app.Inc_phase.ButtonPushedFcn = createCallbackFcn(app, @Inc_phaseButtonPushed, true);
            app.Inc_phase.Text = '+';
            app.Dec_phase = uibutton(app.ParametersPanel, 'push');
            app.Dec_phase.ButtonPushedFcn = createCallbackFcn(app, @Dec_phaseButtonPushed, true);
            app.Dec_phase.Text = '-';
            app.OptionsPanel = uipanel(app.FirstOrderTab);
            app.OptionsPanel.Title = 'Options';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ControllerBodePanel = uipanel(app.FirstOrderTab);
            app.ControllerBodePanel.Title = 'Controller Bode';
            app.Bode_mag = uiaxes(app.ControllerBodePanel);
            title(app.Bode_mag, 'Mag')
            xlabel(app.Bode_mag, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag, 'Mag [dB]')
            app.Bode_phase = uiaxes(app.ControllerBodePanel);
            title(app.Bode_phase, 'Phase')
            xlabel(app.Bode_phase, 'Frequency[rad/sec]')
            ylabel(app.Bode_phase, 'Phase[deg]')
            app.SecondOrderTab = uitab(app.TabGroup);
            app.SecondOrderTab.Title = 'Second Order';
            app.Title_2 = uilabel(app.SecondOrderTab);
            app.Title_2.Text = 'Second Order Lead  Controller';
            app.ParametersPanel_2 = uipanel(app.SecondOrderTab);
            app.ParametersPanel_2.Title = 'Parameters';
            app.PhasedegLabel_2 = uilabel(app.ParametersPanel_2);
            app.PhasedegLabel_2.Text = 'Phase [deg]:';
            app.Phase_2 = uieditfield(app.ParametersPanel_2, 'text');
            app.FreqradsecLabel = uilabel(app.ParametersPanel_2);
            app.FreqradsecLabel.Text = 'Freq[rad/sec]:';
            app.Freq_2 = uieditfield(app.ParametersPanel_2, 'text');
            app.AddButton_2 = uibutton(app.ParametersPanel_2, 'push');
            app.AddButton_2.Text = 'Add';
            app.ReadyLampLabel_2 = uilabel(app.ParametersPanel_2);
            app.ReadyLampLabel_2.Text = 'Ready';
            app.Ready_2 = uilamp(app.ParametersPanel_2);
            app.Inc_freq_2 = uibutton(app.ParametersPanel_2, 'push');
            app.Inc_freq_2.Text = '+';
            app.Dec_freq_2 = uibutton(app.ParametersPanel_2, 'push');
            app.Dec_freq_2.Text = '-';
            app.Inc_phase_2 = uibutton(app.ParametersPanel_2, 'push');
            app.Inc_phase_2.Text = '+';
            app.Dec_phase_2 = uibutton(app.ParametersPanel_2, 'push');
            app.Dec_phase_2.Text = '-';
            app.ZetaEditFieldLabel = uilabel(app.ParametersPanel_2);
            app.ZetaEditFieldLabel.Text = 'Zeta';
            app.ZetaEditField = uieditfield(app.ParametersPanel_2, 'text');
            app.Inc_phase_3 = uibutton(app.ParametersPanel_2, 'push');
            app.Inc_phase_3.Text = '+';
            app.Dec_phase_3 = uibutton(app.ParametersPanel_2, 'push');
            app.Dec_phase_3.Text = '-';
            app.ControllerBodePanel_2 = uipanel(app.SecondOrderTab);
            app.ControllerBodePanel_2.Title = 'Controller Bode';
            app.Bode_mag_2 = uiaxes(app.ControllerBodePanel_2);
            title(app.Bode_mag_2, 'Mag')
            xlabel(app.Bode_mag_2, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag_2, 'Mag [dB]')
            app.Bode_phase_2 = uiaxes(app.ControllerBodePanel_2);
            title(app.Bode_phase_2, 'Phase')
            xlabel(app.Bode_phase_2, 'Frequency[rad/sec]')
            ylabel(app.Bode_phase_2, 'Phase[deg]')
            app.OptionsPanel_2 = uipanel(app.SecondOrderTab);
            app.OptionsPanel_2.Title = 'Options';
            app.SaveExitButton_2 = uibutton(app.OptionsPanel_2, 'push');
            app.SaveExitButton_2.Text = 'Save & Exit';
            app.CancelButton_2 = uibutton(app.OptionsPanel_2, 'push');
            app.CancelButton_2.Text = 'Cancel';
            app.HelpButton_2 = uibutton(app.OptionsPanel_2, 'push');
            app.HelpButton_2.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = leadLagGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end