classdef loopShapingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoopShapingGUI                  matlab.ui.Figure
        ControllerPanel                 matlab.ui.container.Panel
        LeadLag                         matlab.ui.control.Button
        Notch                           matlab.ui.control.Button
        Gain                            matlab.ui.control.Button
        PID                             matlab.ui.control.Button
        AddPolesZeros                   matlab.ui.control.Button
        AutomaticDesigner               matlab.ui.control.Button
        ResetContoller                  matlab.ui.control.Button
        ControllerText                  matlab.ui.control.Label
        CurrentController               matlab.ui.control.Label
        EditControllerEditFieldLabel    matlab.ui.control.Label
        EditCont_num                    matlab.ui.control.EditField
        EditButton                      matlab.ui.control.Button
        DeleteControllerEditFieldLabel  matlab.ui.control.Label
        DelCont_num                     matlab.ui.control.EditField
        DeleteButton                    matlab.ui.control.Button
        ControllersList                 matlab.ui.control.ListBox
        Lag                             matlab.ui.control.Button
        NicholsBodePanel                matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NicholsTab                      matlab.ui.container.Tab
        nicholsPlot                     matlab.ui.control.UIAxes
        CloseLoopBodeTab                matlab.ui.container.Tab
        CLbodePlot                      matlab.ui.control.UIAxes
        OpenLoopBodeTab                 matlab.ui.container.Tab
        OLbodePlot_mag                  matlab.ui.control.UIAxes
        OLbodePlot_phase                matlab.ui.control.UIAxes
        ControllerPolesandZerosPanel    matlab.ui.container.Panel
        zpkMap                          matlab.ui.control.UIAxes
        zpkTable_p                      matlab.ui.control.Table
        zpkTable_z                      matlab.ui.control.Table
        DragPolesandZerosButton         matlab.ui.control.Button
        OptionsPanel                    matlab.ui.container.Panel
        SaveController                  matlab.ui.control.Button
        LoadController                  matlab.ui.control.Button
        LoadPlant                       matlab.ui.control.Button
        Help                            matlab.ui.control.Button
        Discrete                        matlab.ui.control.Button
        savenExit                       matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        LoopShapingGUILabel             matlab.ui.control.Label
        QFTLogo                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        % dialogApp prop. is used to communicate between the main gui and
        % the controllers sub-guis
        DialogApp_gain;
        DialogApp_lead;
        DialogApp_notch;
        DialogApp_lag;
        DialogApp_pid;
        DialogApp_pz;
        newPoint; % newPoint, p, z will be used for pzmap and the drag poles/zeros feature
        p
        z
        p_lead;
        p_lag;
        p_notch;
        p_pid;
        p_pz;
        z_lead;
        z_lag;
        z_notch;
        z_pid;
        z_pz;
    end
    
    properties (Access = public)
        G ;  % the controller from the w.s
        Controller;
        ini_cont; % the controller from w.s, used when edit / del or update the controller
        des; % des from the w.s
        % flags for chainging in the controller.
        gain_flag;
        lead_flag;
        lag_flag;
        pid_flag;
        notch_flag;
        pz_flag;
    end
    
    methods (Access = private)
        function [] = printGtext(app,G,addButtonPushed)
            %this function print the controller in a text format - may not
            %be needed because print function in qctrl
            if addButtonPushed ~= 100
                [p,z] = pzmap(G);
                Gctrl = qctrl(z,p,1);
                Gstr = print(Gctrl);
                app.CurrentController.Text = Gstr;
            end
        end
        function [] = printCLbode(app,G,des)
            %plotting bode in the correct axes in the GUI. not using
            %bodeplot of qctrl becuase its not suppors app designer.
            wnom = des.nom.frequency;
            Cwnom = squeeze(freqresp(G,wnom)); % in complex plain
            Fwnom = squeeze(freqresp(tf(1),wnom)); % in complex plain
            Pnom = n2c(des.nom.response);
            Lnom = Cwnom.*Pnom;
            Tnom = Fwnom.*Lnom./(1+Lnom);
            T_mag = mag2db(abs(Tnom));
            semilogx(app.CLbodePlot,wnom,T_mag)
            app.CLbodePlot.Title.String = "Close Loop Bode";
            app.CLbodePlot.XLabel.String = "Frequency [rad/s]";
            app.CLbodePlot.XGrid = 'On';
            app.CLbodePlot.YGrid = 'On';
            axis(app.CLbodePlot,'tight')
            box(app.CLbodePlot,'on')
        end
        function [] = printOLbode(app,G,des)
            %plotting bode in the correct axes in the GUI. not using
            %bodeplot of qctrl becuase its not suppors app designer.
            wnom = des.nom.frequency;
            Cwnom = squeeze(freqresp(G,wnom)); % in complex plain
            Fwnom = squeeze(freqresp(tf(1),wnom)); % in complex plain
            Pnom = n2c(des.nom.response);
            Lnom = Cwnom.*Pnom;
            L_mag = mag2db(abs(Lnom));
            L_phase = rad2deg(angle(Lnom));
            
            semilogx(app.OLbodePlot_mag,wnom,L_mag)
            app.OLbodePlot_mag.Title.String = "Open Loop Bode - Mag";
            app.OLbodePlot_mag.XLabel.String = "Frequency [rad/s]";
            app.OLbodePlot_mag.XGrid = 'On';
            app.OLbodePlot_mag.YGrid = 'On';
            axis(app.OLbodePlot_mag,'tight')
            box(app.OLbodePlot_mag,'on')
            
            semilogx(app.OLbodePlot_phase,wnom,L_phase)
            app.OLbodePlot_phase.Title.String = "Open Loop Bode - Phase";
            app.OLbodePlot_phase.XLabel.String = "Frequency [rad/s]";
            app.OLbodePlot_phase.XGrid = 'On';
            app.OLbodePlot_phase.YGrid = 'On';
            axis(app.OLbodePlot_phase,'tight')
            box(app.OLbodePlot_phase,'on')
        end
        function [] = printPZmap(app,addButtonPushed)
            %this function plot the pz map of the controller, and fills the
            %pz map with values. currently there is a use of pzmap - need
            %to change it.
            if addButtonPushed ~= 100
                
                app.zpkTable_p.Data = {};
                app.zpkTable_z.Data = {};
                sump = 0;
                sumz = 0;
                
                [P,Z] = pzmap(app.ini_cont);
                if ~isempty(P)
                        plot(app.zpkMap,real(P),imag(P),'xk','LineWidth',1)
                        hold(app.zpkMap,'on')
                        for i=1:length(P)
                            sys_poles(i,:)= {'System pole' real(P(i)) imag(P(i))}; %sample data for each column
                            s = uistyle('FontColor','black');
                            addStyle(app.zpkTable_p,s,'cell',[i,1]);
                            addStyle(app.zpkTable_p,s,'cell',[i,2]);
                            addStyle(app.zpkTable_p,s,'cell',[i,3]);
                        end
                    sump = i;
                end
                
                if ~isempty(Z)
                        plot(app.zpkMap,real(Z),imag(Z),'xk','LineWidth',1)
                        hold(app.zpkMap,'on')
                        for i=1:length(Z)
                            sys_zeros(i,:)= {'System zero' real(Z(i)) imag(Z(i))}; %sample data for each column
                            s = uistyle('FontColor','black');
                            addStyle(app.zpkTable_z,s,'cell',[i,1]);
                            addStyle(app.zpkTable_z,s,'cell',[i,2]);
                            addStyle(app.zpkTable_z,s,'cell',[i,3]);
                        end
                    sumz = i;
                end
                
                for i = 1 : length(app.Controller.lead)
                    [P_lead, Z_lead] = pzmap(app.Controller.lead(i));
                    if ~isempty(P_lead)
                        plot(app.zpkMap,real(P_lead),imag(P_lead),'xr','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(P_lead)
                            sys_poles(sump+k,:) = {'Lead pole' real(P_lead(k)) imag(P_lead(k))}; %sample data for each column
                            s = uistyle('FontColor','red');
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,1]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,2]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,3]);
                    end
                    sump = sump + k;
                    end
                    if ~isempty(Z_lead)
                        plot(app.zpkMap,real(Z_lead),imag(Z_lead),'or','LineWidth',1)
                        hold(app.zpkMap,'on')
                        for k=1:length(Z_lead)
                            sys_zeros(sumz+k,:) = {'Lead zero' real(Z_lead(k)) imag(Z_lead(k))}; %sample data for each column
                            s = uistyle('FontColor','red');
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,1]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,2]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,3]);
                        end
                    sumz = sumz + k;
                    end
                    
                end
                
                for i = 1 : length(app.Controller.lag)
                    [P_lag, Z_lag] = pzmap(app.Controller.lag(i));
                    if ~isempty(P_lag)
                        plot(app.zpkMap,real(P_lag),imag(P_lag),'xb','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(P_lag)
                            sys_poles(sump+k,:) = {'Lag pole' real(P_lag(k)) imag(P_lag(k))}; %sample data for each column
                            s = uistyle('FontColor','blue');
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,1]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,2]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,3]);
                    end
                    sump = sump + k;
                    end
                    if ~isempty(Z_lag)
                        plot(app.zpkMap,real(Z_lag),imag(Z_lag),'ob','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(Z_lag)
                            sys_zeros(sumz+k,:) = {'Lag zero' real(Z_lag(k)) imag(Z_lag(k))}; %sample data for each column
                            s = uistyle('FontColor','blue');
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,1]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,2]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,3]);
                    end
                    sumz = sumz + k;
                    end
                end
                
                for i = 1 : length(app.Controller.pid)
                    [P_pid, Z_pid] = pzmap(app.Controller.pid(i));
                    if ~isempty(P_pid)
                        plot(app.zpkMap,real(P_pid),imag(P_pid),'xm','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(P_pid)
                            sys_poles(sump+k,:) = {'PID pole' real(P_pid(k)) imag(P_pid(k))}; %sample data for each column
                            s = uistyle('FontColor','m');
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,1]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,2]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,3]);
                    end
                    sump = sump + k;
                    end
                    if ~isempty(Z_pid)
                        plot(app.zpkMap,real(Z_pid),imag(Z_pid),'om','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(Z_pid)
                            sys_zeros(sumz+k,:) = {'PID zero' real(Z_pid(k)) imag(Z_pid(k))}; %sample data for each column
                            s = uistyle('FontColor','m');
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,1]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,2]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,3]);
                    end
                    sumz = sumz + k;
                    end
                end
                
                for i = 1 : length(app.Controller.notch)
                    [P_notch, Z_notch] = pzmap(app.Controller.notch(i));
                    if ~isempty(P_notch)
                        plot(app.zpkMap,real(P_notch),imag(P_notch),'xg','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(P_notch)
                            sys_poles(sump+k,:) = {'Notch pole' real(P_notch(k)) imag(P_notch(k))}; %sample data for each column
                            s = uistyle('FontColor','green');
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,1]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,2]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,3]);
                    end
                    sump = sump + k;
                    end
                    if ~isempty(Z_notch)
                        plot(app.zpkMap,real(Z_notch),imag(Z_notch),'og','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(Z_notch)
                            sys_zeros(sumz+k,:) = {'Notch zero' real(Z_notch(k)) imag(Z_notch(k))}; %sample data for each column
                            s = uistyle('FontColor','green');
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,1]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,2]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,3]);
                    end
                    sumz = sumz + k;
                    end
                end
                
                for i = 1 : length(app.Controller.pz)
                    [P_pz, Z_pz] = pzmap(app.Controller.pz(i));
                    if ~isempty(P_pz)
                        plot(app.zpkMap,real(P_pz),imag(P_pz),'xm','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(P_pz)
                            sys_poles(sump+k,:) = {'Pole' real(P_pz(k)) imag(P_pz(k))}; %sample data for each column
                            s = uistyle('FontColor','m');
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,1]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,2]);
                            addStyle(app.zpkTable_p,s,'cell',[sump+k,3]);
                    end
                    sump = sump + k;
                    end
                    if ~isempty(Z_pz)
                        plot(app.zpkMap,real(Z_pz),imag(Z_pz),'om','LineWidth',1)
                        hold(app.zpkMap,'on')
                    for k=1:length(Z_pz)
                            sys_zeros(sumz+k,:) = {'Zero' real(Z_pz(k)) imag(Z_pz(k))}; %sample data for each column
                            s = uistyle('FontColor','m');
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,1]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,2]);
                            addStyle(app.zpkTable_z,s,'cell',[sumz+k,3]);
                    end
                    sumz = sumz + k;
                    end
                end
                
                plot(app.zpkMap,[-10 10],[0 0],'k','linewidth',1)
                hold(app.zpkMap,'on')
                plot(app.zpkMap,[0 0],[-10 10],'k','linewidth',1)
                hold(app.zpkMap,'on')
                app.zpkMap.XMinorGrid = 'On';
                app.zpkMap.YMinorGrid = 'On';
                app.zpkMap.Title.String = "Pole - Zero Map";
                app.zpkMap.XLabel.String = "Real";
                app.zpkMap.YLabel.String = "Imaginary";
                xlim(app.zpkMap,[-5 5]);
                ylim(app.zpkMap,[-2 2]);
                
                if ~isempty(sys_zeros)
                    app.zpkTable_z.Data = [app.zpkTable_z.Data(:);sys_zeros];
                end
                if ~isempty(sys_poles)
                    app.zpkTable_p.Data = [app.zpkTable_p.Data(:);sys_poles]; 
                end
                
            end
            
        end
        function [] = printPZTable(app,addButtonPushed)
            %this function print the poles and zeros in the table
            if addButtonPushed ~=100
                %fill pz table

                [app.p,app.z] = pzmap(app.ini_cont);
                [app.p_lead, app.z_lead] = pzmap(app.Controller.lead);
                [app.p_lag, app.z_lag] = pzmap(app.Controller.lag);
                [app.p_notch, app.z_notch] = pzmap(app.Controller.notch);
                [app.p_pid, app.z_pid] = pzmap(app.Controller.pid);
                [app.p_pz, app.z_pz] = pzmap(app.Controller.pz);

                
%                 app.zpkTable_p.Data = {};
%                 app.zpkTable_z.Data = {};
                
%                 sum = 0;        
                
%                 if ~isempty(app.p)
%                     for i=1:length(app.p)
%                         sys_poles(i,:)= {'System pole' real(app.p(i)) imag(app.p(i))}; %sample data for each column
%                         s = uistyle('FontColor','black');
%                         addStyle(app.zpkTable_p,s,'cell',[i,1]);
%                         addStyle(app.zpkTable_p,s,'cell',[i,2]);
%                         addStyle(app.zpkTable_p,s,'cell',[i,3]);
%                     end
%                     sum = i;
%                 end
                
%                 if ~isempty(app.p_lead)
%                     for i=1:length(app.p_lead)
%                         sys_poles(sum+i,:) = {'Lead pole' real(app.p_lead(i)) imag(app.p_lead(i))}; %sample data for each column
%                         s = uistyle('FontColor','red');
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,3]);
%                     end
%                     sum = sum + i;
%                 end
                
%                 if ~isempty(app.p_lag)
%                     for i=1:length(app.p_lag)
%                         sys_poles(sum+i,:) = {'Lag pole' real(app.p_lag(i)) imag(app.p_lag(i))}; %sample data for each column
%                         s = uistyle('FontColor','blue');
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,3]);
%                     end
%                     sum = sum + i;
%                 end
                
%                 if ~isempty(app.p_notch)
%                     for i=1:length(app.p_notch)
%                         sys_poles(sum+i,:) = {'Notch pole' real(app.p_notch(i)) imag(app.p_notch(i))}; %sample data for each column
%                         s = uistyle('FontColor','green');
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,3]);
%                     end
%                     sum = sum + i;
%                 end
                
%                 if ~isempty(app.p_pid)
%                     for i=1:length(app.p_pid)
%                         sys_poles(sum+i,:) = {'PID pole' real(app.p_pid(i)) imag(app.p_pid(i))}; %sample data for each column
%                         s = uistyle('FontColor','m');
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,3]);
%                     end
%                     sum = sum + i;
%                 end
                
%                 if ~isempty(app.p_pz)
%                     for i=1:length(app.p_pz)
%                         sys_poles(sum+i,:) = {'Pole' real(app.p_pz(i)) imag(app.p_pz(i))}; %sample data for each column
%                         s = uistyle('FontColor','m');
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_p,s,'cell',[sum+i,3]);
%                     end
%                 end
                
%                 if ~isempty(sys_poles)
%                 app.zpkTable_p.Data = [app.zpkTable_p.Data(:);sys_poles]; 
%                 end
                
                %------------------------------------zeros-----------------------------------%
                
%                 sum = 0; %reset sum for the zeros
%                 fprintf('\n app.z size = %d,%d',size(app.z_lead))
                
%                 if ~isempty(app.z)
%                     for i=1:length(app.z)
%                         sys_zeros(i,:)= {'System zero' real(app.z(i)) imag(app.z(i))}; %sample data for each column
%                         s = uistyle('FontColor','black');
%                         addStyle(app.zpkTable_z,s,'cell',[i,1]);
%                         addStyle(app.zpkTable_z,s,'cell',[i,2]);
%                         addStyle(app.zpkTable_z,s,'cell',[i,3]);
%                     end
%                     sum = i;
%                 end
                
%                 if ~isempty(app.z_lead)
%                     for i=1:length(app.z_lead)
%                         sys_zeros(sum+i,:) = {'Lead zero' real(app.z_lead(i)) imag(app.z_lead(i))}; %sample data for each column
%                         s = uistyle('FontColor','red');
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,3]);
%                     end
%                     sum = sum + i;
%                 end
                
%                 if ~isempty(app.z_lag)
%                     for i=1:length(app.z_lag)
%                         sys_zeros(sum+i,:) = {'Lag zero' real(app.z_lag(i)) imag(app.z_lag(i))}; %sample data for each column
%                         s = uistyle('FontColor','blue');
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,3]);
%                     end
%                     sum = sum + i;
%                 end
                
%                 if ~isempty(app.z_notch)
%                     for i=1:length(app.z_notch)
%                         sys_zeros(sum+i,:) = {'Notch zero' real(app.z_notch(i)) imag(app.z_notch(i))}; %sample data for each column
%                         s = uistyle('FontColor','green');
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,3]);
%                     end
%                     sum = sum + i;
%                 end
                
%                 if ~isempty(app.z_pid)
%                     for i=1:length(app.z_pid)
%                         sys_zeros(sum+i,:) = {'PID zero' real(app.z_pid(i)) imag(app.z_pid(i))}; %sample data for each column
%                         s = uistyle('FontColor','m');
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,3]);
%                     end
%                     sum = sum + i;
%                 end
                
%                 if ~isempty(app.z_pz)
%                     for i=1:length(app.z_pz)
%                         sys_zeros(sum+i,:) = {'Zero' real(app.z_pz(i)) imag(app.z_pz(i))}; %sample data for each column
%                         s = uistyle('FontColor','m');
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,1]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,2]);
%                         addStyle(app.zpkTable_z,s,'cell',[sum+i,3]);
%                     end
%                 end
                
%                 if ~isempty(sys_zeros)
%                 app.zpkTable_z.Data = [app.zpkTable_z.Data(:);sys_zeros];
%                 end
                
            end
        end      
        function [] = printCONTlist(app,type,reset,editMode,addButtonPushed)
            %prints the controller list. inputs are the type of the
            %controller - lead,lag etc... , reset : if the user reset the
            %design, the persistant i and j resets. editMode - if editMode
            % =1, no need to incearse i and j.
            persistent i j
            if addButtonPushed ~= 100
                
                if isempty(i)
                    i = 1;
                end
                if isempty(j)
                    j = 0;
                end
                
                if reset == 1
                    i = 1;
                end
                
                if editMode ~= 0
                    j = i;
                    i = editMode;
                end
                
                if strcmp(type,'gain')
                    app.ControllersList.Items(i+1) = {sprintf('#%d - gain',i+1)} ;
                    if j ~= 0
                        i = j ;
                        j = 0;
                    else
                        i = i+1;
                    end
                end
                
                if strcmp(type,'lead')
                    app.ControllersList.Items(i+1) = {sprintf('#%d - lead',i+1)} ;
                    if j ~= 0
                        i = j ;
                        j = 0;
                    else
                        i = i+1;
                    end
                end
                
                if strcmp(type,'notch')
                    app.ControllersList.Items(i+1) = {sprintf('#%d - notch',i+1)} ;
                    if j ~= 0
                        i = j ;
                        j = 0;
                    else
                        i = i+1;
                    end
                end
                
                if strcmp(type,'lag')
                    app.ControllersList.Items(i+1) = {sprintf('#%d - lag',i+1)} ;
                    if j ~= 0
                        i = j ;
                        j = 0;
                    else
                        i = i+1;
                    end
                end
                
                if strcmp(type,'pid')
                    app.ControllersList.Items(i+1) = {sprintf('#%d - pid',i+1)} ;
                    if j ~= 0
                        i = j ;
                        j = 0;
                    else
                        i = i+1;
                    end
                end
                if strcmp(type,'pz')
                    app.ControllersList.Items(i+1) = {sprintf('#%d - pz',i+1)} ;
                    if j ~= 0
                        i = j ;
                        j = 0;
                    else
                        i = i+1;
                    end
                end
            end
            
            
        end
        function [] = printHSbnd(app,des)
            %prints H-S bound in the nichols chart. need to replace ngrid
            %with qngrid from qctrl.
            app.nicholsPlot.cla;
            sepcs_num = length(des.bnd);
            freq_num = length(des.bnd(1).w);
            
            for i = 1 : 1 : sepcs_num
                for j = 1 : 1 : freq_num
                    bounds_data = des.bnd(i).c;
                    print_bound = bounds_data{j,1};
                    plot(app.nicholsPlot,real(print_bound),imag(print_bound),'LineWidth',1.3)
                    hold(app.nicholsPlot,'on')
                end
            end
            
            ngrid(app.nicholsPlot)
            app.nicholsPlot.Title.String = "Nichols Plot";
            app.nicholsPlot.XLabel.String = "Phase [deg]";
            app.nicholsPlot.YLabel.String = "Mag [dB]";
            hold(app.nicholsPlot,"on")
            axis(app.nicholsPlot,[-360 -180 -90 0])
            box(app.nicholsPlot,'on')
        end
        function [] = updateGnic(app,G,des)
            %print the open loop nichols in the nichols chart.
            Lnom = series(des.nom,G);
            t_color = des.col;
            plot(app.nicholsPlot,Lnom.phase,Lnom.mag,'k','LineWidth',1.2);
            hold(app.nicholsPlot,"on")
            for k=1:length(des.tpl)
                str = sprintf(' %g',des.tpl(k).frequency);
                tk = qfr(des.tpl(k).template(1),des.tpl(k).frequency);
                Ltpl = series(tk,G);
                x_text = Ltpl.phase;
                y_text = Ltpl.mag;
                plot(app.nicholsPlot,Ltpl.phase,Ltpl.mag,'marker','square',...
                    'markeredgecolor','k','markerfacecolor',t_color(k,:));
                text(app.nicholsPlot,x_text,y_text,str,'clipping','on') % single space added
            end
            hold(app.nicholsPlot,"off")
            axis(app.nicholsPlot,'tight')
            box(app.nicholsPlot,'on')
            xlim(app.nicholsPlot,[-360 0]);
        end
        function [] = updateData(app,G,des,addButtonPushed)
            %update the bode, nichols and pz map.
            
            printCLbode(app,G,des);
            printOLbode(app,G,des);
            printHSbnd(app,des);
            
            updateGnic(app,G,des);
            
            printPZmap(app,addButtonPushed);
            printGtext(app,G,addButtonPushed);
            printPZTable(app,addButtonPushed);
        end
        function [] = updateG(app,Controller)
            %update the controller after adding/edit/delete element
            initial_cont = app.ini_cont;
            TotalGain = 1;
            TotalLead = 1;
            TotalLag = 1;
            TotalPID = 1;
            TotalNotch = 1;
            TotalPZ = 1;
            
            for i=1:length(Controller.gain)
                TotalGain = TotalGain * Controller.gain(i);
            end
            
            for i=1:length(Controller.lead)
                TotalLead = TotalLead * Controller.lead(i);
            end
            
            for i=1:length(Controller.lag)
                TotalLag = TotalLag * Controller.lag(i);
            end
            
            for i=1:length(Controller.pid)
                TotalPID = TotalPID * Controller.pid(i);
            end
            
            for i=1:length(Controller.notch)
                TotalNotch = TotalNotch * Controller.notch(i);
            end
            
            for i=1:length(Controller.pz)
                TotalPZ = TotalPZ * Controller.pz(i);
            end
            
            app.G = initial_cont*TotalGain*TotalLead*TotalLag*TotalPID*TotalNotch*TotalPZ;
            
        end
        function place = findContPlace(app,list, cont_num, cont_str)
            % this funcion return the place of the element inside the controller.type
            % struct. its inputs are the controller list, the controller number in the
            % list and the controller string in the list.
            Cont = app.ini_cont;
            str_4comp_gain = sprintf('#%d - gain',cont_num);
            str_4comp_lead = sprintf('#%d - lead',cont_num);
            str_4comp_lag = sprintf('#%d - lag',cont_num);
            str_4comp_pid = sprintf('#%d - pid',cont_num);
            str_4comp_notch = sprintf('#%d - notch',cont_num);
            str_4comp_zero = sprintf('#%d - pz',cont_num);

            counter = 0;

            switch cont_str{1}
                case str_4comp_gain
                    for i = 2 : cont_num
                        if strcmp(list(i),sprintf('#%d - gain',i)) || strcmp(list(i),sprintf('---Deleted Gain---'))  
                            counter = counter +1;
                        end
                    end
                case str_4comp_lead
                    for i = 2 : cont_num
                        if strcmp(list(i),sprintf('#%d - lead',i)) || strcmp(list(i),sprintf('---Deleted Lead---'))
                            counter = counter +1;
                        end
                    end
                case str_4comp_lag
                    for i = 2 : cont_num
                        if strcmp(list(i),sprintf('#%d - lag',i)) || strcmp(list(i),sprintf('---Deleted Lag---'))
                            counter = counter +1;
                        end
                    end
                case str_4comp_pid
                    for i = 2 : cont_num
                        if strcmp(list(i),sprintf('#%d - pid',i)) || strcmp(list(i),sprintf('---Deleted PID---'))
                            counter = counter +1;
                        end
                    end
                case str_4comp_notch
                    for i = 2 : cont_num
                        if strcmp(list(i),sprintf('#%d - notch',i)) || strcmp(list(i),sprintf('---Deleted Notch---'))
                            counter = counter +1;
                        end
                    end
                case str_4comp_zero
                    for i = 2 : cont_num
                        if strcmp(list(i),sprintf('#%d - pz',i)) || strcmp(list(i),sprintf('---Deleted PZ---'))
                            counter = counter +1;
                        end
                    end
            end
            place = counter;
            fprintf('\nYou edited/deleted the %d controller of type: - %s controller ,the controller number is %d\n', place, cont_str{1}, cont_num)
end
    end
    
    methods (Access = public)
        function [] = updateGainCont(app,G,des,gain,gain_flag,EditMode)
            %all the updates of the elements works in the same way:
            %(/type)_flag means that the user didnt press cancel at the
            %sub-gui. if he didnt pressed enter, there are 3 options:
            %adding new controller - then EditMode is 0, and a new item
            %goes to the app.controller.(/type) struct. if the user pressed
            %Add in the sub-gui, there is no need to save the controller in
            %the controller struct, just update the plots in the main gui,
            %so EditMode == 100. if the user edit one of the controllers
            %from the list, EditMode will be equal to the place of the
            %controller in the app.controller struct.
            persistent i
            if isempty(i)
                i = 1;
            end
            if gain_flag == 1 && EditMode == 0
                app.Controller.gain(i) = gain;
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'gain',0,0,1);
                i = i+1;
            end
            if gain_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.gain(EditMode) = gain;
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'gain',0,EditMode,1);
            end
            if gain_flag == 1 && EditMode ==100
                app.Controller.gain(i) = gain;
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,100)
            end
        end
        function [] = updateLeadCont(app,G,des,phase,freq,order,lead_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if lead_flag == 1 && EditMode == 0
                app.Controller.lead(i) = Clead(phase,freq,order);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'lead',0,0,1);
                i = i+1;
            end
            if lead_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.lead(EditMode) = Clead(phase,freq,order);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'lead',0,EditMode,1);
            end
            if lead_flag == 1 && EditMode == 100
                app.Controller.lead(i) = Clead(phase,freq,order);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,100)
            end
        end
        function [] = updateNotchCont(app,G,des,w0n,w0d,zn,zd,notch_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if notch_flag == 1 && EditMode == 0
                app.Controller.notch(i) = Cnotch(w0n,w0d,zn,zd);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'notch',0,0,1);
                i = i+1;
            end
            if notch_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.notch(EditMode) =  Cnotch(w0n,w0d,zn,zd);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'notch',0,EditMode,1);
            end
            if notch_flag == 1 && EditMode == 100
                app.Controller.notch(i) = Cnotch(w0n,w0d,zn,zd);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,100)
            end
        end
        function [] = updateLagCont(app,G,des,freq,beta,lag_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if lag_flag == 1 && EditMode == 0
                app.Controller.lag(i) = Clag(freq,beta);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'lag',0,0,1);
                i = i+1;
            end
            if lag_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.lag(EditMode) = Clag(freq,beta);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'lag',0,EditMode,1);
            end
            if lag_flag == 1 && EditMode == 100
                app.Controller.lag(i) = Clag(freq,beta);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,100)
            end
        end
        function [] = updatePidCont(app,G,des,kp,ki,kd,tf,tau,flag,pid_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if pid_flag == 1 && EditMode == 0
                app.Controller.pid(i) = CPID(ki,kp,kd,tf,tau,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'pid',0,0,1);
                i = i+1;
            end
            if pid_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.pid(EditMode) = CPID(ki,kp,kd,tf,tau,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                printCONTlist(app,'pid',0,EditMode,1);
            end
            if pid_flag == 1 && EditMode == 100
                app.Controller.pid(i) = CPID(ki,kp,kd,tf,tau,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,100)
            end
        end
        function [] = updatePZCont(app,G,des,a,b,c,d,flag,pz_flag,EditMode)
            
            persistent i
            if isempty(i)
                i = 1;
            end
            if pz_flag == 1 && EditMode == 0
                app.Controller.pz(i) = Cpz(a,b,c,d,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,des,1);
                % here i need the interactive part of the pz map
                printCONTlist(app,'pz',0,0,1);
                i = i+1;
            end
            if pz_flag == 1 && EditMode ~= 0
                app.Controller.pz(EditMode) = Cpz(a,b,c,d,flag);
                updateG(app,app.Controller);
                printCLbode(app,G,des);
                printOLbode(app,G,des)
                printHSbnd(app,des)
                updateGnic(app,G,des);
                % here i need the interactive part of the pz map
                printCONTlist(app,'pz',0,EditMode,1);
                printPZmap(app,1)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Cont, Des)
            %print the controller on text
            clc
            app.G = Cont;
            app.ini_cont = Cont;
            app.des = Des;
            printGtext(app,app.G,1);
            app.ControllersList.Items(1) = {sprintf('#1 - Initial Controller - G')} ;
            %print CL bode
            printCLbode(app,app.G,app.des);
            %print H-S bounds and the open loop on nichols chart
            printHSbnd(app,app.des);
            %print OL bode
            printOLbode(app,app.G,app.des);
            %plotting the open loop on nichols
            updateGnic(app,app.G,app.des)
            
            % define the controller elements
            app.Controller.gain(1) = 1;
            app.Controller.lead(1) = tf(1);
            app.Controller.lag(1) = tf(1);
            app.Controller.pid(1) = tf(1);
            app.Controller.notch(1) = tf(1);
            app.Controller.pz(1) = tf(1);
            
            %print pz map
            printPZmap(app,1)
            %print pz table
            printPZTable(app,1)
        end

        % Size changed function: NicholsTab
        function NicholsTabSizeChanged(app, event)
            %run this when user pressing on the nichols tab.
            app.nicholsPlot.cla;
            printHSbnd(app,app.des);
            updateGnic(app,app.G,app.des);
        end

        % Size changed function: CloseLoopBodeTab
        function CloseLoopBodeTabSizeChanged(app, event)
            %run this when user pressing on the Close Loop Bode tab.
            app.CLbodePlot.cla
            printCLbode(app,app.G,app.des);
        end

        % Size changed function: OpenLoopBodeTab
        function OpenLoopBodeTabSizeChanged(app, event)
            %run this when user pressing on the Open Loop Bode tab.
            app.OLbodePlot_mag.cla
            app.OLbodePlot_phase.cla
            printOLbode(app,app.G,app.des);
        end

        % Button pushed function: ResetContoller
        function ResetContollerButtonPushed(app, event)
            % reset controller
            app.G = app.ini_cont;
            printCONTlist(app,'',1,0,1);
            app.ControllersList.Items = {};
            app.ControllersList.Items(1) = {sprintf('#1 - Initial Controller - G')} ;
            app.nicholsPlot.cla
            app.CLbodePlot.cla
            app.OLbodePlot_mag.cla
            app.OLbodePlot_phase.cla
            app.zpkMap.cla
            
            app.Controller.gain(:) = 1;
            app.Controller.lead(:) = tf(1);
            app.Controller.lag(:) = tf(1);
            app.Controller.pid(:) = tf(1);
            app.Controller.notch(:) = tf(1);
            app.Controller.pz(:) = tf(1);
            
            
            updateData(app,app.G,app.des,1)
        end

        % Button pushed function: LeadLag
        function LeadLagButtonPushed(app, event)
            %run this when user pressing on the lead controller button.
            %editMode is 0 because this for new controller design.
            editMode = 0;
            app.DialogApp_lead = leadLagGUI(app,app.G,app.des,editMode);
        end

        % Button pushed function: Gain
        function GainButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_gain = gainGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: Lag
        function LagButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_lag = lagGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: Notch
        function NotchButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_notch = notchGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: PID
        function PIDButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_pid = pidGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: AddPolesZeros
        function AddPolesZerosButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_pz = pzGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: DragPolesandZerosButton
        function DragPolesandZerosButtonPushed(app, event)
            f = mouse_picks(app.G,app.Controller);
            uiwait(f);
            newPos = evalin("base",'newPos');
            type = newPos{3};
            fprintf(type);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            %this is whats running when user press delete controller. the
            %callback function get the place in the controller list and the
            %type of the controller. then it uses findContPlace to find the
            %controller place inside the controller struct. it replace it
            %with 1.
            cont_num = str2double(app.DelCont_num.Value);
            cont_str = app.ControllersList.Items(cont_num);
            list = app.ControllersList.Items;
            %             app.ControllersList.Items{cont_num} = {sprintf("----")};
            
            str_4comp_gain = sprintf('#%d - gain',cont_num);
            str_4comp_lead = sprintf('#%d - lead',cont_num);
            str_4comp_lag = sprintf('#%d - lag',cont_num);
            str_4comp_pid = sprintf('#%d - pid',cont_num);
            str_4comp_notch = sprintf('#%d - notch',cont_num);
            str_4comp_zero = sprintf('#%d - pz',cont_num);
            
            if strcmp(string(cont_str),str_4comp_gain)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted Gain---')};
                place = findContPlace(app,list,cont_num,cont_str);
                app.zpkMap.cla
                app.Controller.gain(place) = 1;
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,1);
            end
            
            if strcmp(string(cont_str),str_4comp_lead)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted Lead---')};
                place = findContPlace(app,list,cont_num,cont_str);
                app.zpkMap.cla
                app.Controller.lead(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,1);
            end
            
            if strcmp(string(cont_str),str_4comp_notch)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted Notch---')};
                place = findContPlace(app,list,cont_num,cont_str);
                app.zpkMap.cla
                app.Controller.notch(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,1);
            end
            
            if strcmp(string(cont_str),str_4comp_lag)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted Lag---')};
                place = findContPlace(app,list,cont_num,cont_str);
                app.zpkMap.cla
                app.Controller.lag(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,1);
            end
            
            if strcmp(string(cont_str),str_4comp_pid)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted PID---')};
                place = findContPlace(app,list,cont_num,cont_str);
                app.zpkMap.cla
                app.Controller.pid(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,1);
            end
            
            if strcmp(string(cont_str),str_4comp_zero)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted PZ---')};
                place = findContPlace(app,list,cont_num,cont_str);
                app.zpkMap.cla
                app.Controller.pz(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des,1);
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            %this is whats running when press edit in the main gui.pretty
            %similar to the delete option. we calculates the place of the
            %controller in the controller struct, and then edit it with the
            %sub gui of the controller.
            cont_num = str2double(app.EditCont_num.Value);
            cont_str = app.ControllersList.Items(cont_num);
            list = app.ControllersList.Items;
            str_4comp_gain = sprintf('#%d - gain',cont_num);
            str_4comp_lead = sprintf('#%d - lead',cont_num);
            str_4comp_lag = sprintf('#%d - lag',cont_num);
            str_4comp_pid = sprintf('#%d - pid',cont_num);
            str_4comp_notch = sprintf('#%d - notch',cont_num);
            str_4comp_zero = sprintf('#%d - pz',cont_num);
            
            if strcmp(string(cont_str),str_4comp_gain)
                place = findContPlace(app,list,cont_num,cont_str);
                app.DialogApp_gain = gainGUI(app,app.G,app.des,place);
            end
            if strcmp(string(cont_str),str_4comp_lead)
                place = findContPlace(app,list,cont_num,cont_str);
                app.DialogApp_lead = leadLagGUI(app,app.G,app.des,place);
            end
            if strcmp(string(cont_str),str_4comp_notch)
                place = findContPlace(app,list,cont_num,cont_str);
                app.DialogApp_notch = notchGUI(app,app.G,app.des,place);
            end
            if strcmp(string(cont_str),str_4comp_lag)
                place = findContPlace(app,list,cont_num,cont_str);
                app.DialogApp_lag = lagGUI(app,app.G,app.des,place);
            end
            if strcmp(string(cont_str),str_4comp_pid)
                place = findContPlace(app,list,cont_num,cont_str);
                app.DialogApp_pid = pidGUI(app,app.G,app.des,place);
            end
            
            if strcmp(string(cont_str),str_4comp_zero)
                place = findContPlace(app,list,cont_num,cont_str);
                app.DialogApp_pz = pzGUI(app,app.G,app.des,place);
            end
        end

        % Button pushed function: Discrete
        function DiscreteButtonPushed(app, event)
            
        end

        % Button pushed function: SaveController
        function SaveControllerButtonPushed(app, event)
            assignin("base","G",app.G)
            disp("Saved the newly designed controller as G in the workspace.")
        end

        % Button pushed function: savenExit
        function savenExitButtonPushed(app, event)
            assignin("base","G",app.G)
            disp("Saved the newly designed controller as G in the workspace.")
            closereq()
        end

        % Button pushed function: LoadController
        function LoadControllerButtonPushed(app, event)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq()
        end

        % Button pushed function: LoadPlant
        function LoadPlantButtonPushed(app, event)
            
        end

        % Window button down function: LoopShapingGUI
        function LoopShapingGUIWindowButtonDown(app, event)
            %             persistent x y ind counter element
            %             %first press to get the pole - location and number. second
            %             %press to move it and plot
            %             if isempty(counter)
            %                 counter = 1;
            %             end
            %
            %             if isempty(element)
            %                 element = 1;
            %             end
            %
            %             if mod(counter,2)
            %                 app.zpkMap.cla
            %                 counter = counter +1;
            %                 for i = 1 : length(app.p)
            %                     plot(app.zpkMap,real(app.p),imag(app.p),'xr')
            %                     hold(app.zpkMap,"on")
            %                     line(app.zpkMap,[-15 15],[0 0])
            %                     hold(app.zpkMap,"on")
            %                     line(app.zpkMap,[0 0],[-15 15])
            %                 end
            %             else
            %                 pos = app.zpkMap.CurrentPoint; %pos
            %                 x = app.p(1);
            %                 y = app.p(2);
            %                 [~,ind] = min(sum((x-pos(1)).^2+(y-pos(3)).^2,1));
            %                 x(ind) = pos(1);
            %                 y(ind) = pos(3);
            %                 [app.p, app.z] = pzmap(app.G);
            %                 app.p(1) = x(ind) + 1i*y(ind);
            %                 counter = counter + 1;
            %             end
            %
        end

        % Cell edit callback: zpkTable_p
        function zpkTable_pCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoopShapingGUI = uifigure('Visible', 'off');
            app.LoopShapingGUI.Name = 'UI Figure';
            app.LoopShapingGUI.WindowButtonDownFcn = createCallbackFcn(app, @LoopShapingGUIWindowButtonDown, true);
            app.ControllerPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPanel.Title = 'Controller';
            app.LeadLag = uibutton(app.ControllerPanel, 'push');
            app.LeadLag.ButtonPushedFcn = createCallbackFcn(app, @LeadLagButtonPushed, true);
            app.LeadLag.Text = 'Lead';
            app.Notch = uibutton(app.ControllerPanel, 'push');
            app.Notch.ButtonPushedFcn = createCallbackFcn(app, @NotchButtonPushed, true);
            app.Notch.Text = 'Notch/anti Notch';
            app.Gain = uibutton(app.ControllerPanel, 'push');
            app.Gain.ButtonPushedFcn = createCallbackFcn(app, @GainButtonPushed, true);
            app.Gain.Text = 'Gain';
            app.PID = uibutton(app.ControllerPanel, 'push');
            app.PID.ButtonPushedFcn = createCallbackFcn(app, @PIDButtonPushed, true);
            app.PID.Text = 'PID';
            app.AddPolesZeros = uibutton(app.ControllerPanel, 'push');
            app.AddPolesZeros.ButtonPushedFcn = createCallbackFcn(app, @AddPolesZerosButtonPushed, true);
            app.AddPolesZeros.Text = 'Add Poles/Zeros';
            app.AutomaticDesigner = uibutton(app.ControllerPanel, 'push');
            app.AutomaticDesigner.Text = 'Automatic Designer';
            app.ResetContoller = uibutton(app.ControllerPanel, 'push');
            app.ResetContoller.ButtonPushedFcn = createCallbackFcn(app, @ResetContollerButtonPushed, true);
            app.ResetContoller.Text = 'Reset Contoller';
            app.ControllerText = uilabel(app.ControllerPanel);
            app.ControllerText.Text = 'Current Contoller:';
            app.CurrentController = uilabel(app.ControllerPanel);
            app.CurrentController.Text = 'G(s) = 1';
            app.EditControllerEditFieldLabel = uilabel(app.ControllerPanel);
            app.EditControllerEditFieldLabel.Text = 'Edit Controller #:';
            app.EditCont_num = uieditfield(app.ControllerPanel, 'text');
            app.EditButton = uibutton(app.ControllerPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteControllerEditFieldLabel = uilabel(app.ControllerPanel);
            app.DeleteControllerEditFieldLabel.Text = 'Delete Controller #:';
            app.DelCont_num = uieditfield(app.ControllerPanel, 'text');
            app.DeleteButton = uibutton(app.ControllerPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ControllersList = uilistbox(app.ControllerPanel);
            app.ControllersList.Items = {'#1 - Initial Controller - G'};
            app.ControllersList.Value = {};
            app.Lag = uibutton(app.ControllerPanel, 'push');
            app.Lag.ButtonPushedFcn = createCallbackFcn(app, @LagButtonPushed, true);
            app.Lag.Text = {'Lag'; ''};
            app.NicholsBodePanel = uipanel(app.LoopShapingGUI);
            app.NicholsBodePanel.Title = 'Nichols / Bode';
            app.TabGroup = uitabgroup(app.NicholsBodePanel);
            app.NicholsTab = uitab(app.TabGroup);
            app.NicholsTab.SizeChangedFcn = createCallbackFcn(app, @NicholsTabSizeChanged, true);
            app.NicholsTab.Title = 'Nichols';
            app.nicholsPlot = uiaxes(app.NicholsTab);
            title(app.nicholsPlot, 'Nichols')
            xlabel(app.nicholsPlot, 'Phase [deg]')
            ylabel(app.nicholsPlot, 'Mag [dB]')
            app.CloseLoopBodeTab = uitab(app.TabGroup);
            app.CloseLoopBodeTab.SizeChangedFcn = createCallbackFcn(app, @CloseLoopBodeTabSizeChanged, true);
            app.CloseLoopBodeTab.Title = 'Close Loop Bode';
            app.CLbodePlot = uiaxes(app.CloseLoopBodeTab);
            title(app.CLbodePlot, 'Close Loop Bode')
            xlabel(app.CLbodePlot, 'Frequency [rad/sec]')
            ylabel(app.CLbodePlot, 'Mag')
            app.OpenLoopBodeTab = uitab(app.TabGroup);
            app.OpenLoopBodeTab.SizeChangedFcn = createCallbackFcn(app, @OpenLoopBodeTabSizeChanged, true);
            app.OpenLoopBodeTab.Title = 'Open Loop Bode';
            app.OLbodePlot_mag = uiaxes(app.OpenLoopBodeTab);
            title(app.OLbodePlot_mag, 'Open Loop Mag')
            xlabel(app.OLbodePlot_mag, 'Frequency [rad/sec]')
            ylabel(app.OLbodePlot_mag, 'Mag')
            app.OLbodePlot_phase = uiaxes(app.OpenLoopBodeTab);
            title(app.OLbodePlot_phase, 'Open Loop Phase')
            xlabel(app.OLbodePlot_phase, 'Frequency [rad/sec]')
            ylabel(app.OLbodePlot_phase, 'Phase [deg]')
            app.ControllerPolesandZerosPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPolesandZerosPanel.Title = 'Controller Poles and Zeros';
            app.zpkMap = uiaxes(app.ControllerPolesandZerosPanel);
            title(app.zpkMap, 'Title')
            xlabel(app.zpkMap, 'X')
            ylabel(app.zpkMap, 'Y')
            app.zpkTable_p = uitable(app.ControllerPolesandZerosPanel);
            app.zpkTable_p.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_p.RowName = {''; ''; ''};
            app.zpkTable_p.ColumnEditable = true;
            app.zpkTable_p.CellEditCallback = createCallbackFcn(app, @zpkTable_pCellEdit, true);
            app.zpkTable_z = uitable(app.ControllerPolesandZerosPanel);
            app.zpkTable_z.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_z.RowName = {''; ''; ''};
            app.zpkTable_z.ColumnEditable = true;
            app.DragPolesandZerosButton = uibutton(app.ControllerPolesandZerosPanel, 'push');
            app.DragPolesandZerosButton.ButtonPushedFcn = createCallbackFcn(app, @DragPolesandZerosButtonPushed, true);
            app.DragPolesandZerosButton.Text = 'Drag Poles and Zeros';
            app.OptionsPanel = uipanel(app.LoopShapingGUI);
            app.OptionsPanel.Title = 'Options';
            app.SaveController = uibutton(app.OptionsPanel, 'push');
            app.SaveController.ButtonPushedFcn = createCallbackFcn(app, @SaveControllerButtonPushed, true);
            app.SaveController.Text = 'Save Controller To Workspace';
            app.LoadController = uibutton(app.OptionsPanel, 'push');
            app.LoadController.ButtonPushedFcn = createCallbackFcn(app, @LoadControllerButtonPushed, true);
            app.LoadController.Text = 'Load Controller From Workspace';
            app.LoadPlant = uibutton(app.OptionsPanel, 'push');
            app.LoadPlant.ButtonPushedFcn = createCallbackFcn(app, @LoadPlantButtonPushed, true);
            app.LoadPlant.Text = 'Load Plant From Workspace';
            app.Help = uibutton(app.OptionsPanel, 'push');
            app.Help.Text = 'Help!';
            app.Discrete = uibutton(app.OptionsPanel, 'push');
            app.Discrete.ButtonPushedFcn = createCallbackFcn(app, @DiscreteButtonPushed, true);
            app.Discrete.Text = 'Discrete Design';
            app.savenExit = uibutton(app.OptionsPanel, 'push');
            app.savenExit.ButtonPushedFcn = createCallbackFcn(app, @savenExitButtonPushed, true);
            app.savenExit.Text = 'Save Controller to Workspace and Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoopShapingGUILabel = uilabel(app.LoopShapingGUI);
            app.LoopShapingGUILabel.Text = 'Loop Shaping GUI';
            app.QFTLogo = uiimage(app.LoopShapingGUI);
            app.QFTLogo.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.LoopShapingGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loopShapingGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoopShapingGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoopShapingGUI)
        end
    end
end