classdef notchGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Title                    matlab.ui.control.Label
        ParametersPanel          matlab.ui.container.Panel
        EnterButton              matlab.ui.control.Button
        w0nradsecEditFieldLabel  matlab.ui.control.Label
        w0n                      matlab.ui.control.EditField
        w0dradsecEditFieldLabel  matlab.ui.control.Label
        w0d                      matlab.ui.control.EditField
        znEditFieldLabel         matlab.ui.control.Label
        zn                       matlab.ui.control.EditField
        zdEditFieldLabel         matlab.ui.control.Label
        zd                       matlab.ui.control.EditField
        ReadyLampLabel           matlab.ui.control.Label
        Ready                    matlab.ui.control.Lamp
        OptionsPanel             matlab.ui.container.Panel
        HelpButton               matlab.ui.control.Button
        SaveExitButton           matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        ControllerBodePanel      matlab.ui.container.Panel
        Bode_mag                 matlab.ui.control.UIAxes
        Bode_phase               matlab.ui.control.UIAxes
        s2w0n22znsw0n1Label      matlab.ui.control.Label
        s2w0d22zdsw0d1Label      matlab.ui.control.Label
        Label                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        w0n_loc;
        w0d_loc;
        zn_loc;
        zd_loc;
        edit_flag;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
           app.CallingApp = mainapp;
           app.G = G;
           app.des = des;
           app.edit_flag = EditMode;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            cnotch = Cnotch(str2double(app.w0n.Value),str2double(app.w0d.Value),str2double(app.zn.Value),str2double(app.zd.Value));
            [mag, phase_local, w0] = bode(cnotch);
            semilogx(app.Bode_mag,w0,squeeze(mag(1,1,:)));
            semilogx(app.Bode_phase,w0,squeeze(phase_local(1,1,:)));
            app.w0n_loc = str2double(app.w0n.Value);
            app.w0d_loc = str2double(app.w0d.Value);
            app.zn_loc = str2double(app.zn.Value);
            app.zd_loc = str2double(app.zd.Value);
            
            if ~isempty(app.w0n_loc) && ~isempty(app.w0d_loc) && ~isempty(app.zn_loc) && ~isempty(app.zd_loc)
                app.Ready.Color = [0,1,0];
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            notch_flag = 0;
            updateNotchCont(app.CallingApp,app.G,app.des,app.w0n_loc,app.w0d_loc,app.zn_loc,app.zd_loc,notch_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            notch_flag = 1;
            updateNotchCont(app.CallingApp,app.G,app.des,app.w0n_loc,app.w0d_loc,app.zn_loc,app.zd_loc,notch_flag,app.edit_flag)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Notch Controller';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.EnterButton = uibutton(app.ParametersPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.w0nradsecEditFieldLabel = uilabel(app.ParametersPanel);
            app.w0nradsecEditFieldLabel.Text = 'w0n [rad/sec';
            app.w0n = uieditfield(app.ParametersPanel, 'text');
            app.w0dradsecEditFieldLabel = uilabel(app.ParametersPanel);
            app.w0dradsecEditFieldLabel.Text = 'w0d [rad/sec';
            app.w0d = uieditfield(app.ParametersPanel, 'text');
            app.znEditFieldLabel = uilabel(app.ParametersPanel);
            app.znEditFieldLabel.Text = 'zn';
            app.zn = uieditfield(app.ParametersPanel, 'text');
            app.zdEditFieldLabel = uilabel(app.ParametersPanel);
            app.zdEditFieldLabel.Text = 'zd';
            app.zd = uieditfield(app.ParametersPanel, 'text');
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.Ready = uilamp(app.ParametersPanel);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.Text = 'Help';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ControllerBodePanel = uipanel(app.UIFigure);
            app.ControllerBodePanel.Title = 'Controller Bode';
            app.Bode_mag = uiaxes(app.ControllerBodePanel);
            title(app.Bode_mag, 'Bode - Mag')
            xlabel(app.Bode_mag, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag, 'Mag')
            app.Bode_phase = uiaxes(app.ControllerBodePanel);
            title(app.Bode_phase, 'Bode - Phase')
            xlabel(app.Bode_phase, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase, 'Phase')
            app.s2w0n22znsw0n1Label = uilabel(app.UIFigure);
            app.s2w0n22znsw0n1Label.Text = '((s^2)/(w0n^2)+2*zn*(s/w0n)+1)';
            app.s2w0d22zdsw0d1Label = uilabel(app.UIFigure);
            app.s2w0d22zdsw0d1Label.Text = '((s^2)/(w0d^2)+2*zd*(s/w0d)+1)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-------------------------------------------';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = notchGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end