classdef pidGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        StandardPIDTab        matlab.ui.container.Tab
        Title                 matlab.ui.control.Label
        ParametersPanel       matlab.ui.container.Panel
        AddButton             matlab.ui.control.Button
        KpLabel               matlab.ui.control.Label
        Kp                    matlab.ui.control.EditField
        KiLabel               matlab.ui.control.Label
        Ki                    matlab.ui.control.EditField
        KdLabel               matlab.ui.control.Label
        Kd                    matlab.ui.control.EditField
        TfsLabel              matlab.ui.control.Label
        Tf                    matlab.ui.control.EditField
        ReadyLampLabel        matlab.ui.control.Label
        Ready                 matlab.ui.control.Lamp
        kp_inc                matlab.ui.control.Button
        kp_dec                matlab.ui.control.Button
        ki_inc                matlab.ui.control.Button
        ki_dec                matlab.ui.control.Button
        kd_inc                matlab.ui.control.Button
        kd_dec                matlab.ui.control.Button
        tf_inc                matlab.ui.control.Button
        tf_dec                matlab.ui.control.Button
        ContollerBodePanel    matlab.ui.container.Panel
        Bode_mag              matlab.ui.control.UIAxes
        Bode_phase            matlab.ui.control.UIAxes
        kpkiskdsTfs1Label     matlab.ui.control.Label
        OptionsPanel          matlab.ui.container.Panel
        HelpButton            matlab.ui.control.Button
        SaveExitButton        matlab.ui.control.Button
        CancelButton          matlab.ui.control.Button
        sPIDTab_s             matlab.ui.container.Tab
        Title_s               matlab.ui.control.Label
        ParametersPanel_s     matlab.ui.container.Panel
        EnterButton_s         matlab.ui.control.Button
        KpLabel_2             matlab.ui.control.Label
        Kp_s                  matlab.ui.control.EditField
        KiLabel_2             matlab.ui.control.Label
        Ki_s                  matlab.ui.control.EditField
        KdLabel_2             matlab.ui.control.Label
        Kd_s                  matlab.ui.control.EditField
        TfsecLabel            matlab.ui.control.Label
        Tf_s                  matlab.ui.control.EditField
        Tau_dsecLabel         matlab.ui.control.Label
        Taud_s                matlab.ui.control.EditField
        ReadyLampLabel_2      matlab.ui.control.Label
        Ready_s               matlab.ui.control.Lamp
        skp_inc               matlab.ui.control.Button
        skp_dec               matlab.ui.control.Button
        ski_inc               matlab.ui.control.Button
        ski_dec               matlab.ui.control.Button
        skd_inc               matlab.ui.control.Button
        skd_dec               matlab.ui.control.Button
        stf_inc               matlab.ui.control.Button
        stf_dec               matlab.ui.control.Button
        std_inc               matlab.ui.control.Button
        std_dec               matlab.ui.control.Button
        OptionsPanel_s        matlab.ui.container.Panel
        HelpButton_s          matlab.ui.control.Button
        SaveExitButton_s      matlab.ui.control.Button
        CancelButton_s        matlab.ui.control.Button
        ContollerBodePanel_s  matlab.ui.container.Panel
        Bode_mag_s            matlab.ui.control.UIAxes
        Bode_phase_s          matlab.ui.control.UIAxes
        kp1kistaudsLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        kp;
        kp_s;
        kd;
        kd_s;
        ki;
        ki_s;
        tf;
        tf_s;
        taud;
        edit_flag; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
           app.CallingApp = mainapp;
           app.G = G;
           app.des = des;
           app.edit_flag = EditMode; 
           
           app.Kp.Value = num2str(2);
           app.Ki.Value = num2str(1);
           app.Kd.Value = num2str(0.5);
           app.Tf.Value = num2str(0.2);
           
           app.Kp_s.Value = num2str(2);
           app.Ki_s.Value = num2str(0.5);
           app.Kd_s.Value = num2str(0.5);
           app.Tf_s.Value = num2str(0.2);
           app.Taud_s.Value = num2str(0.2);
           
           
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            cpid = CPID(str2double(app.Ki.Value),str2double(app.Kp.Value),str2double(app.Kd.Value),str2double(app.Tf.Value),[],0);
            [mag, phase_local, w0] = bode(cpid);
            semilogx(app.Bode_mag,w0,squeeze(mag(1,1,:)));
            semilogx(app.Bode_phase,w0,squeeze(phase_local(1,1,:)));
            app.kp = str2double(app.Kp.Value);
            app.ki = str2double(app.Ki.Value);
            app.kd = str2double(app.Kd.Value);
            app.tf = str2double(app.Tf.Value);
            
            if ~isempty(app.kp) && ~isempty(app.ki) && ~isempty(app.kd) && ~isempty(app.tf)
                app.Ready.Color = [0,1,0];
            end
            
            pid_flag = 1;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp,app.ki,app.kd,app.tf,[],0,pid_flag,100)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            pid_flag = 0;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp,app.ki,app.kd,app.tf,[],0,pid_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            pid_flag = 1;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp,app.ki,app.kd,app.tf,[],0,pid_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: EnterButton_s
        function EnterButton_sPushed(app, event)
            cpid = CPID(str2double(app.Ki_s.Value),str2double(app.Kp_s.Value),str2double(app.Kd_s.Value),str2double(app.Tf_s.Value),str2double(app.Taud_s.Value),1);
            [mag, phase_local, w0] = bode(cpid);
            semilogx(app.Bode_mag_s,w0,squeeze(mag(1,1,:)));
            semilogx(app.Bode_phase_s,w0,squeeze(phase_local(1,1,:)));
            app.kp_s = str2double(app.Kp_s.Value);
            app.ki_s = str2double(app.Ki_s.Value);
            app.kd_s = str2double(app.Kd_s.Value);
            app.tf_s = str2double(app.Tf_s.Value);
            app.taud = str2double(app.Taud_s.Value);
            
            if ~isempty(app.kp_s) && ~isempty(app.ki_s) && ~isempty(app.kd_s) && ~isempty(app.tf_s) && ~isempty(app.taud)
                app.Ready_s.Color = [0,1,0];
            end
            pid_flag = 1;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp_s,app.ki_s,app.kd_s,app.tf_s,app.taud,1,pid_flag,100)
        end

        % Button pushed function: CancelButton_s
        function CancelButton_sPushed(app, event)
            pid_flag = 0;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp_s,app.ki_s,app.kd_s,app.tf_s,app.taud,1,pid_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: SaveExitButton_s
        function SaveExitButton_sPushed(app, event)
            pid_flag = 1;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp_s,app.ki_s,app.kd_s,app.tf_s,app.taud,1,pid_flag,app.edit_flag)
            delete(app);   
        end

        % Button pushed function: kp_inc
        function kp_incButtonPushed(app, event)
             app.kp = str2double(app.Kp.Value);
             app.Kp.Value = num2str(app.kp + 0.5);
        end

        % Button pushed function: kp_dec
        function kp_decButtonPushed(app, event)
            app.kp = str2double(app.Kp.Value);
            app.Kp.Value = num2str(app.kp - 0.5);
        end

        % Button pushed function: ki_inc
        function ki_incButtonPushed(app, event)
            app.ki = str2double(app.Ki.Value);
            app.Ki.Value = num2str(app.ki + 0.5);
        end

        % Button pushed function: ki_dec
        function ki_decButtonPushed(app, event)
            app.ki = str2double(app.Ki.Value);
            app.Ki.Value = num2str(app.ki - 0.5);
        end

        % Button pushed function: kd_inc
        function kd_incButtonPushed(app, event)
            app.kd = str2double(app.Kd.Value);
            app.Kd.Value = num2str(app.kd + 0.5);
        end

        % Button pushed function: kd_dec
        function kd_decButtonPushed(app, event)
            app.kd = str2double(app.Kd.Value);
            app.Kd.Value = num2str(app.kd - 0.5);
        end

        % Button pushed function: tf_inc
        function tf_incButtonPushed(app, event)
            app.tf = str2double(app.Tf.Value);
            app.Tf.Value = num2str(app.tf+0.1);
        end

        % Button pushed function: tf_dec
        function tf_decButtonPushed(app, event)
            app.tf = str2double(app.Tf.Value);
            app.Tf.Value = num2str(app.tf*0.8);
        end

        % Button pushed function: skp_inc
        function skp_incButtonPushed(app, event)
            app.kp_s = str2double(app.Kp_s.Value);      
            app.Kp_s.Value = num2str(app.kp_s + 0.5);
        end

        % Button pushed function: skp_dec
        function skp_decButtonPushed(app, event)
            app.kp_s = str2double(app.Kp_s.Value);
            app.Kp_s.Value = num2str(app.kp_s - 0.5);
        end

        % Button pushed function: ski_inc
        function ski_incButtonPushed(app, event)
            app.ki_s = str2double(app.Ki_s.Value);
            app.Ki_s.Value = num2str(app.ki_s + 0.5);
        end

        % Button pushed function: ski_dec
        function ski_decButtonPushed(app, event)
            app.ki_s = str2double(app.Ki_s.Value);
            app.Ki_s.Value = num2str(app.ki_s - 0.5);
        end

        % Button pushed function: skd_inc
        function skd_incButtonPushed(app, event)
            app.kd_s = str2double(app.Kd_s.Value);
            app.Kd_s.Value = num2str(app.kd_s + 0.5);
        end

        % Button pushed function: skd_dec
        function skd_decButtonPushed(app, event)
            app.kd_s = str2double(app.Kd_s.Value);
            app.Kd_s.Value = num2str(app.kd_s - 0.5);
        end

        % Button pushed function: stf_inc
        function stf_incButtonPushed(app, event)
            app.tf_s = str2double(app.Tf_s.Value);
            app.Tf_s.Value = num2str(app.tf_s + 0.1);
        end

        % Button pushed function: stf_dec
        function stf_decButtonPushed(app, event)
            app.tf_s = str2double(app.Tf_s.Value);
            app.Tf_s.Value = num2str(app.tf_s*0.8);
        end

        % Button pushed function: std_inc
        function std_incButtonPushed(app, event)
            app.taud = str2double(app.Taud_s.Value);
            app.Taud_s.Value = num2str(app.taud + 0.1);
        end

        % Button pushed function: std_dec
        function std_decButtonPushed(app, event)
            app.taud = str2double(app.Taud_s.Value);
            app.Taud_s.Value = num2str(app.taud*0.8);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpGUI('pid')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StandardPIDTab = uitab(app.TabGroup);
            app.StandardPIDTab.Title = 'Standard P.I.D';
            app.Title = uilabel(app.StandardPIDTab);
            app.Title.Text = 'Standard PID Controller';
            app.ParametersPanel = uipanel(app.StandardPIDTab);
            app.ParametersPanel.Title = 'Parameters';
            app.AddButton = uibutton(app.ParametersPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.KpLabel = uilabel(app.ParametersPanel);
            app.KpLabel.Text = 'Kp:';
            app.Kp = uieditfield(app.ParametersPanel, 'text');
            app.KiLabel = uilabel(app.ParametersPanel);
            app.KiLabel.Text = 'Ki:';
            app.Ki = uieditfield(app.ParametersPanel, 'text');
            app.KdLabel = uilabel(app.ParametersPanel);
            app.KdLabel.Text = 'Kd:';
            app.Kd = uieditfield(app.ParametersPanel, 'text');
            app.TfsLabel = uilabel(app.ParametersPanel);
            app.TfsLabel.Text = 'Tf [s]:';
            app.Tf = uieditfield(app.ParametersPanel, 'text');
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.Ready = uilamp(app.ParametersPanel);
            app.kp_inc = uibutton(app.ParametersPanel, 'push');
            app.kp_inc.ButtonPushedFcn = createCallbackFcn(app, @kp_incButtonPushed, true);
            app.kp_inc.Text = '+';
            app.kp_dec = uibutton(app.ParametersPanel, 'push');
            app.kp_dec.ButtonPushedFcn = createCallbackFcn(app, @kp_decButtonPushed, true);
            app.kp_dec.Text = '-';
            app.ki_inc = uibutton(app.ParametersPanel, 'push');
            app.ki_inc.ButtonPushedFcn = createCallbackFcn(app, @ki_incButtonPushed, true);
            app.ki_inc.Text = '+';
            app.ki_dec = uibutton(app.ParametersPanel, 'push');
            app.ki_dec.ButtonPushedFcn = createCallbackFcn(app, @ki_decButtonPushed, true);
            app.ki_dec.Text = '-';
            app.kd_inc = uibutton(app.ParametersPanel, 'push');
            app.kd_inc.ButtonPushedFcn = createCallbackFcn(app, @kd_incButtonPushed, true);
            app.kd_inc.Text = '+';
            app.kd_dec = uibutton(app.ParametersPanel, 'push');
            app.kd_dec.ButtonPushedFcn = createCallbackFcn(app, @kd_decButtonPushed, true);
            app.kd_dec.Text = '-';
            app.tf_inc = uibutton(app.ParametersPanel, 'push');
            app.tf_inc.ButtonPushedFcn = createCallbackFcn(app, @tf_incButtonPushed, true);
            app.tf_inc.Text = '+';
            app.tf_dec = uibutton(app.ParametersPanel, 'push');
            app.tf_dec.ButtonPushedFcn = createCallbackFcn(app, @tf_decButtonPushed, true);
            app.tf_dec.Text = '-';
            app.ContollerBodePanel = uipanel(app.StandardPIDTab);
            app.ContollerBodePanel.Title = 'Contoller Bode';
            app.Bode_mag = uiaxes(app.ContollerBodePanel);
            title(app.Bode_mag, 'Mag')
            xlabel(app.Bode_mag, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag, 'Mag')
            app.Bode_phase = uiaxes(app.ContollerBodePanel);
            title(app.Bode_phase, 'Phase')
            xlabel(app.Bode_phase, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase, 'Phase')
            app.kpkiskdsTfs1Label = uilabel(app.StandardPIDTab);
            app.kpkiskdsTfs1Label.Text = 'kp+ki/s+(kd*s)/(Tf*s+1)';
            app.OptionsPanel = uipanel(app.StandardPIDTab);
            app.OptionsPanel.Title = 'Options';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.sPIDTab_s = uitab(app.TabGroup);
            app.sPIDTab_s.Title = ' Time Design P.I.D';
            app.Title_s = uilabel(app.sPIDTab_s);
            app.Title_s.Text = 'Time Design PID Controller';
            app.ParametersPanel_s = uipanel(app.sPIDTab_s);
            app.ParametersPanel_s.Title = 'Parameters';
            app.EnterButton_s = uibutton(app.ParametersPanel_s, 'push');
            app.EnterButton_s.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_sPushed, true);
            app.EnterButton_s.Text = 'Add';
            app.KpLabel_2 = uilabel(app.ParametersPanel_s);
            app.KpLabel_2.Text = 'Kp:';
            app.Kp_s = uieditfield(app.ParametersPanel_s, 'text');
            app.KiLabel_2 = uilabel(app.ParametersPanel_s);
            app.KiLabel_2.Text = 'Ki:';
            app.Ki_s = uieditfield(app.ParametersPanel_s, 'text');
            app.KdLabel_2 = uilabel(app.ParametersPanel_s);
            app.KdLabel_2.Text = 'Kd:';
            app.Kd_s = uieditfield(app.ParametersPanel_s, 'text');
            app.TfsecLabel = uilabel(app.ParametersPanel_s);
            app.TfsecLabel.Text = 'Tf [sec]';
            app.Tf_s = uieditfield(app.ParametersPanel_s, 'text');
            app.Tau_dsecLabel = uilabel(app.ParametersPanel_s);
            app.Tau_dsecLabel.Text = 'Tau_d [sec]:';
            app.Taud_s = uieditfield(app.ParametersPanel_s, 'text');
            app.ReadyLampLabel_2 = uilabel(app.ParametersPanel_s);
            app.ReadyLampLabel_2.Text = 'Ready';
            app.Ready_s = uilamp(app.ParametersPanel_s);
            app.skp_inc = uibutton(app.ParametersPanel_s, 'push');
            app.skp_inc.ButtonPushedFcn = createCallbackFcn(app, @skp_incButtonPushed, true);
            app.skp_inc.Text = '+';
            app.skp_dec = uibutton(app.ParametersPanel_s, 'push');
            app.skp_dec.ButtonPushedFcn = createCallbackFcn(app, @skp_decButtonPushed, true);
            app.skp_dec.Text = '-';
            app.ski_inc = uibutton(app.ParametersPanel_s, 'push');
            app.ski_inc.ButtonPushedFcn = createCallbackFcn(app, @ski_incButtonPushed, true);
            app.ski_inc.Text = '+';
            app.ski_dec = uibutton(app.ParametersPanel_s, 'push');
            app.ski_dec.ButtonPushedFcn = createCallbackFcn(app, @ski_decButtonPushed, true);
            app.ski_dec.Text = '-';
            app.skd_inc = uibutton(app.ParametersPanel_s, 'push');
            app.skd_inc.ButtonPushedFcn = createCallbackFcn(app, @skd_incButtonPushed, true);
            app.skd_inc.Text = '+';
            app.skd_dec = uibutton(app.ParametersPanel_s, 'push');
            app.skd_dec.ButtonPushedFcn = createCallbackFcn(app, @skd_decButtonPushed, true);
            app.skd_dec.Text = '-';
            app.stf_inc = uibutton(app.ParametersPanel_s, 'push');
            app.stf_inc.ButtonPushedFcn = createCallbackFcn(app, @stf_incButtonPushed, true);
            app.stf_inc.Text = '+';
            app.stf_dec = uibutton(app.ParametersPanel_s, 'push');
            app.stf_dec.ButtonPushedFcn = createCallbackFcn(app, @stf_decButtonPushed, true);
            app.stf_dec.Text = '-';
            app.std_inc = uibutton(app.ParametersPanel_s, 'push');
            app.std_inc.ButtonPushedFcn = createCallbackFcn(app, @std_incButtonPushed, true);
            app.std_inc.Text = '+';
            app.std_dec = uibutton(app.ParametersPanel_s, 'push');
            app.std_dec.ButtonPushedFcn = createCallbackFcn(app, @std_decButtonPushed, true);
            app.std_dec.Text = '-';
            app.OptionsPanel_s = uipanel(app.sPIDTab_s);
            app.OptionsPanel_s.Title = 'Options';
            app.HelpButton_s = uibutton(app.OptionsPanel_s, 'push');
            app.HelpButton_s.Text = 'Help';
            app.SaveExitButton_s = uibutton(app.OptionsPanel_s, 'push');
            app.SaveExitButton_s.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButton_sPushed, true);
            app.SaveExitButton_s.Text = 'Save & Exit';
            app.CancelButton_s = uibutton(app.OptionsPanel_s, 'push');
            app.CancelButton_s.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_sPushed, true);
            app.CancelButton_s.Text = 'Cancel';
            app.ContollerBodePanel_s = uipanel(app.sPIDTab_s);
            app.ContollerBodePanel_s.Title = 'Contoller Bode';
            app.Bode_mag_s = uiaxes(app.ContollerBodePanel_s);
            title(app.Bode_mag_s, 'Mag')
            xlabel(app.Bode_mag_s, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag_s, 'Mag')
            app.Bode_phase_s = uiaxes(app.ContollerBodePanel_s);
            title(app.Bode_phase_s, 'Phase')
            xlabel(app.Bode_phase_s, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase_s, 'Phase')
            app.kp1kistaudsLabel = uilabel(app.sPIDTab_s);
            app.kp1kistaudsLabel.Text = 'kp(1+ki/s+taud*s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pidGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end